classdef ea3hw1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SlidingBoxTab                   matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        stop_value                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        stop_type                       matlab.ui.control.DropDown
        StopsimulationwhenDropDownLabel  matlab.ui.control.Label
        theta                           matlab.ui.control.NumericEditField
        RampangledegreesEditFieldLabel  matlab.ui.control.Label
        m                               matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        u                               matlab.ui.control.NumericEditField
        CoefficientoffrictionEditFieldLabel  matlab.ui.control.Label
        xaxis                           matlab.ui.control.DropDown
        xaxisDropDownLabel              matlab.ui.control.Label
        position_graph                  matlab.ui.control.UIAxes
        speed_graph                     matlab.ui.control.UIAxes
        energy_graph                    matlab.ui.control.UIAxes
        CollisionTab                    matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button down function: UIFigure
        function RunSlide(app, event)


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Read inputs from GUI
            m = app.m.Value;
            mu = app.u.Value;
            theta = app.theta.Value;

            stopValue = app.stop_value.Value;

            g = 9.81;

            v0 = 0;
            x0 = 0;

            % solve acceleration
            a = g * (sind(theta) - mu*cosd(theta));

            x1 = stopValue;

            if(a <= 0)
                uialert(app.UIFigure, ...
                'The box will not accelerate down the ramp with these inputs.', ...
                'Simulation Error');
                return
            end

            tFinal = sqrt(2*x1/a);

            t = linspace(0,tFinal,200);
            
            % Calculate motion
            x = x0 + v0*t + 0.5*a*t.^2;
            v = v0 + a*t;
            
            % Calculate energy
            KE = 0.5*m*v.^2;


            h0 = x1*sind(theta);
            h1 = h0 - x*sind(theta);

            PE = m*g*h1;

            % Friction
            frictionForce = mu*m*g*cosd(theta);
            thermalEnergy = frictionForce*x;
            totalEnergy = KE + PE + thermalEnergy;

            E = KE+PE + thermalEnergy;

            plot(app.position_graph,t,x,'LineWidth',2)
            title(app.position_graph,'Position vs Time')
            xlabel(app.position_graph,'Time (s)')
            ylabel(app.position_graph,'Position (m)')
            grid(app.position_graph,'on')

            plot(app.speed_graph,t,v,'LineWidth',2)
            title(app.speed_graph,'Speed vs Time')
            xlabel(app.speed_graph,'Time (s)')
            ylabel(app.speed_graph,'Speed (m/s)')
            grid(app.speed_graph,'on')

            plot(app.energy_graph,t,KE,'LineWidth',2)
            hold(app.energy_graph,'on')
            
            plot(app.energy_graph,t,PE,'LineWidth',2)
            plot(app.energy_graph,t,thermalEnergy,'LineWidth',2)
            plot(app.energy_graph,t,totalEnergy,'LineWidth',2)
            
            hold(app.energy_graph,'off')
            
            legend(app.energy_graph, ...
                'Kinetic', ...
                'Potential', ...
                'Thermal', ...
                'Total')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @RunSlide, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SlidingBoxTab = uitab(app.TabGroup);
            app.SlidingBoxTab.Title = 'Sliding Box';
            app.energy_graph = uiaxes(app.SlidingBoxTab);
            title(app.energy_graph, 'Energy vs Time ')
            xlabel(app.energy_graph, 'Time')
            ylabel(app.energy_graph, 'Energy')
            zlabel(app.energy_graph, 'Z')
            app.speed_graph = uiaxes(app.SlidingBoxTab);
            title(app.speed_graph, 'Speed vs Time ')
            xlabel(app.speed_graph, 'X')
            ylabel(app.speed_graph, 'Speed')
            zlabel(app.speed_graph, 'Z')
            app.position_graph = uiaxes(app.SlidingBoxTab);
            title(app.position_graph, 'Position vs Time ')
            xlabel(app.position_graph, 'Time')
            ylabel(app.position_graph, 'Position')
            zlabel(app.position_graph, 'Z')
            app.xaxisDropDownLabel = uilabel(app.SlidingBoxTab);
            app.xaxisDropDownLabel.Text = 'x-axis ';
            app.xaxis = uidropdown(app.SlidingBoxTab);
            app.xaxis.Items = {'along the ground ', 'along the ramp', ''};
            app.xaxis.Value = 'along the ground ';
            app.CoefficientoffrictionEditFieldLabel = uilabel(app.SlidingBoxTab);
            app.CoefficientoffrictionEditFieldLabel.Text = 'Coefficient of friction';
            app.u = uieditfield(app.SlidingBoxTab, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.SlidingBoxTab);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.m = uieditfield(app.SlidingBoxTab, 'numeric');
            app.RampangledegreesEditFieldLabel = uilabel(app.SlidingBoxTab);
            app.RampangledegreesEditFieldLabel.Text = 'Ramp angle (degrees)';
            app.theta = uieditfield(app.SlidingBoxTab, 'numeric');
            app.StopsimulationwhenDropDownLabel = uilabel(app.SlidingBoxTab);
            app.StopsimulationwhenDropDownLabel.Text = 'Stop simulation when';
            app.stop_type = uidropdown(app.SlidingBoxTab);
            app.stop_type.Items = {'position', 'velocity'};
            app.stop_type.Value = 'position';
            app.Label = uilabel(app.SlidingBoxTab);
            app.Label.Text = '=';
            app.stop_value = uieditfield(app.SlidingBoxTab, 'numeric');
            app.RunButton = uibutton(app.SlidingBoxTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Simulation';
            app.CollisionTab = uitab(app.TabGroup);
            app.CollisionTab.Title = 'Collision';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea3hw1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end